classdef app_heartbeat_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LatidoLampLabel            matlab.ui.control.Label
        LatidoLamp                 matlab.ui.control.Lamp
        SeleccioneunarchivoButton  matlab.ui.control.StateButton
        ReproducirButton           matlab.ui.control.StateButton
        UIOriginalSignal           matlab.ui.control.UIAxes
        UIFilteredSignal           matlab.ui.control.UIAxes
        UIProcessedSignal          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ecg;        % señal ecg que se carga directamente de un archivo
        timedEcg;   % señal ecg que va creciendo con el tiempo
        
        
        file;       % nombre del archivo 
        path;       % ruta al archivo 
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SeleccioneunarchivoButton
        function SeleccioneunarchivoButtonValueChanged(app, event)
            value = app.SeleccioneunarchivoButton.Value;
            
            [app.file, app.path] = uigetfile('*.mat');
            filepath = fullfile(app.path, app.file);

            app.ecg = load(filepath, '-mat');


        end

        % Value changed function: ReproducirButton
        function ReproducirButtonValueChanged(app, event)
            value = app.ReproducirButton.Value;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LatidoLampLabel = uilabel(app.UIFigure);
            app.LatidoLampLabel.Text = 'Latido';
            app.LatidoLamp = uilamp(app.UIFigure);
            app.SeleccioneunarchivoButton = uibutton(app.UIFigure, 'state');
            app.SeleccioneunarchivoButton.ValueChangedFcn = createCallbackFcn(app, @SeleccioneunarchivoButtonValueChanged, true);
            app.SeleccioneunarchivoButton.Text = 'Seleccione un archivo';
            app.ReproducirButton = uibutton(app.UIFigure, 'state');
            app.ReproducirButton.ValueChangedFcn = createCallbackFcn(app, @ReproducirButtonValueChanged, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.UIOriginalSignal = uiaxes(app.UIFigure);
            title(app.UIOriginalSignal, 'Señal ECG original')
            xlabel(app.UIOriginalSignal, 'tiempo (secs)')
            ylabel(app.UIOriginalSignal, 'mV')
            app.UIFilteredSignal = uiaxes(app.UIFigure);
            title(app.UIFilteredSignal, 'Señal ECG filtrada')
            xlabel(app.UIFilteredSignal, 'tiempo (secs)')
            ylabel(app.UIFilteredSignal, 'mV')
            app.UIProcessedSignal = uiaxes(app.UIFigure);
            title(app.UIProcessedSignal, 'Integral de la derivada al cuadrado de la señal ECG filtrada')
            xlabel(app.UIProcessedSignal, 'X')
            ylabel(app.UIProcessedSignal, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_heartbeat_detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end